classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        correctionPanel               matlab.ui.container.Panel
        ParameterstotuneButtonGroup   matlab.ui.container.ButtonGroup
        currentCheckBox               matlab.ui.control.CheckBox
        thetaZCheckBox                matlab.ui.control.CheckBox
        thetaYCheckBox                matlab.ui.control.CheckBox
        thetaXCheckBox                matlab.ui.control.CheckBox
        transZCheckBox                matlab.ui.control.CheckBox
        transYCheckBox                matlab.ui.control.CheckBox
        transXCheckBox                matlab.ui.control.CheckBox
        autosearchButton              matlab.ui.control.Button
        DeltaEmeVEditField            matlab.ui.control.NumericEditField
        DeltaEmeVEditFieldLabel       matlab.ui.control.Label
        EnergyeVEditField             matlab.ui.control.NumericEditField
        EnergyeVEditFieldLabel        matlab.ui.control.Label
        PmeasuredTextArea             matlab.ui.control.TextArea
        PmeasuredTextAreaLabel        matlab.ui.control.Label
        BrowseButton_P                matlab.ui.control.Button
        MmeasuredTextArea             matlab.ui.control.TextArea
        MmeasuredTextAreaLabel        matlab.ui.control.Label
        BrowseButton_M                matlab.ui.control.Button
        viewcorrectionButton          matlab.ui.control.Button
        currentASpinner               matlab.ui.control.Spinner
        currentASpinnerLabel          matlab.ui.control.Label
        thetaZSpinner                 matlab.ui.control.Spinner
        thetaZSpinnerLabel            matlab.ui.control.Label
        thetaYSpinner                 matlab.ui.control.Spinner
        thetaYSpinnerLabel            matlab.ui.control.Label
        thetaXSpinner                 matlab.ui.control.Spinner
        thetaXSpinnerLabel            matlab.ui.control.Label
        transZSpinner                 matlab.ui.control.Spinner
        transZSpinnerLabel            matlab.ui.control.Label
        transYSpinner                 matlab.ui.control.Spinner
        transYSpinnerLabel            matlab.ui.control.Label
        transXSpinner                 matlab.ui.control.Spinner
        transXSpinnerLabel            matlab.ui.control.Label
        correctallPanel               matlab.ui.container.Panel
        anglemapSwitch                matlab.ui.control.Switch
        anglemapSwitchLabel           matlab.ui.control.Label
        correctallButton              matlab.ui.control.Button
        BrowseButton_export           matlab.ui.control.Button
        exportdirEditField            matlab.ui.control.EditField
        exportdirEditFieldLabel       matlab.ui.control.Label
        lineardistortionPanel         matlab.ui.container.Panel
        kplotSwitch                   matlab.ui.control.Switch
        kplotSwitchLabel              matlab.ui.control.Label
        viewButton                    matlab.ui.control.Button
        energyeVSpinner               matlab.ui.control.Spinner
        energyeVSpinnerLabel_4        matlab.ui.control.Label
        SethighsymmetrypointsLabel    matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        OTab                          matlab.ui.container.Tab
        energyeVSpinner_O             matlab.ui.control.Spinner
        energyeVSpinnerLabel          matlab.ui.control.Label
        y7Spinner_O                   matlab.ui.control.Spinner
        y7SpinnerLabel                matlab.ui.control.Label
        x7Spinner_O                   matlab.ui.control.Spinner
        x7SpinnerLabel                matlab.ui.control.Label
        x6Spinner_O                   matlab.ui.control.Spinner
        x6SpinnerLabel                matlab.ui.control.Label
        y6Spinner_O                   matlab.ui.control.Spinner
        y6SpinnerLabel                matlab.ui.control.Label
        y5Spinner_O                   matlab.ui.control.Spinner
        y5SpinnerLabel                matlab.ui.control.Label
        x5Spinner_O                   matlab.ui.control.Spinner
        x5SpinnerLabel                matlab.ui.control.Label
        y4Spinner_O                   matlab.ui.control.Spinner
        y4SpinnerLabel                matlab.ui.control.Label
        x4Spinner_O                   matlab.ui.control.Spinner
        x4SpinnerLabel                matlab.ui.control.Label
        x3Spinner_O                   matlab.ui.control.Spinner
        x3SpinnerLabel                matlab.ui.control.Label
        y3Spinner_O                   matlab.ui.control.Spinner
        y3SpinnerLabel                matlab.ui.control.Label
        y2Spinner_O                   matlab.ui.control.Spinner
        y2SpinnerLabel                matlab.ui.control.Label
        x2Spinner_O                   matlab.ui.control.Spinner
        x2SpinnerLabel                matlab.ui.control.Label
        y1Spinner_O                   matlab.ui.control.Spinner
        y1SpinnerLabel                matlab.ui.control.Label
        x1Spinner_O                   matlab.ui.control.Spinner
        x1SpinnerLabel                matlab.ui.control.Label
        previewButton_O               matlab.ui.control.Button
        BrowseButton_O_linear         matlab.ui.control.Button
        OmeasuredEditField            matlab.ui.control.EditField
        OmeasuredEditFieldLabel       matlab.ui.control.Label
        PTab                          matlab.ui.container.Tab
        exportBrowseButton_P_linear   matlab.ui.control.Button
        exportdirEditField_P_linear   matlab.ui.control.EditField
        exportdirEditField_2Label     matlab.ui.control.Label
        linearcorrectionButton_P      matlab.ui.control.Button
        energyeVSpinner_P             matlab.ui.control.Spinner
        energyeVSpinnerLabel_2        matlab.ui.control.Label
        previewButton_P               matlab.ui.control.Button
        y7Spinner_P                   matlab.ui.control.Spinner
        y7SpinnerLabel_2              matlab.ui.control.Label
        x7Spinner_P                   matlab.ui.control.Spinner
        x7SpinnerLabel_2              matlab.ui.control.Label
        y6Spinner_P                   matlab.ui.control.Spinner
        y6SpinnerLabel_2              matlab.ui.control.Label
        x6Spinner_P                   matlab.ui.control.Spinner
        x6SpinnerLabel_2              matlab.ui.control.Label
        y5Spinner_P                   matlab.ui.control.Spinner
        y5SpinnerLabel_2              matlab.ui.control.Label
        x5Spinner_P                   matlab.ui.control.Spinner
        x5SpinnerLabel_2              matlab.ui.control.Label
        y4Spinner_P                   matlab.ui.control.Spinner
        y4SpinnerLabel_2              matlab.ui.control.Label
        x4Spinner_P                   matlab.ui.control.Spinner
        x4SpinnerLabel_2              matlab.ui.control.Label
        y3Spinner_P                   matlab.ui.control.Spinner
        y3SpinnerLabel_2              matlab.ui.control.Label
        x3Spinner_P                   matlab.ui.control.Spinner
        x3SpinnerLabel_2              matlab.ui.control.Label
        y2Spinner_P                   matlab.ui.control.Spinner
        y2SpinnerLabel_2              matlab.ui.control.Label
        x2Spinner_P                   matlab.ui.control.Spinner
        x2SpinnerLabel_2              matlab.ui.control.Label
        y1Spinner_P                   matlab.ui.control.Spinner
        y1SpinnerLabel_2              matlab.ui.control.Label
        x1Spinner_P                   matlab.ui.control.Spinner
        x1Spinner_2Label              matlab.ui.control.Label
        BrowseButton_P_linear         matlab.ui.control.Button
        PmeasuredEditField            matlab.ui.control.EditField
        PmeasuredEditFieldLabel       matlab.ui.control.Label
        MTab                          matlab.ui.container.Tab
        linearcorrectionButton_M      matlab.ui.control.Button
        exportdirEditField_M_linear   matlab.ui.control.EditField
        exportdirEditField_2Label_2   matlab.ui.control.Label
        exportBrowseButton_M_linear   matlab.ui.control.Button
        energyeVSpinner_M             matlab.ui.control.Spinner
        energyeVSpinnerLabel_3        matlab.ui.control.Label
        previewButton_M               matlab.ui.control.Button
        y7Spinner_M                   matlab.ui.control.Spinner
        y7SpinnerLabel_3              matlab.ui.control.Label
        x7Spinner_M                   matlab.ui.control.Spinner
        x7SpinnerLabel_3              matlab.ui.control.Label
        y6Spinner_M                   matlab.ui.control.Spinner
        y6SpinnerLabel_3              matlab.ui.control.Label
        x6Spinner_M                   matlab.ui.control.Spinner
        x6SpinnerLabel_3              matlab.ui.control.Label
        y5Spinner_M                   matlab.ui.control.Spinner
        y5SpinnerLabel_3              matlab.ui.control.Label
        x5Spinner_M                   matlab.ui.control.Spinner
        x5SpinnerLabel_3              matlab.ui.control.Label
        y4Spinner_M                   matlab.ui.control.Spinner
        y4SpinnerLabel_3              matlab.ui.control.Label
        x4Spinner_M                   matlab.ui.control.Spinner
        x4SpinnerLabel_3              matlab.ui.control.Label
        y3Spinner_M                   matlab.ui.control.Spinner
        y3SpinnerLabel_3              matlab.ui.control.Label
        x3Spinner_M                   matlab.ui.control.Spinner
        x3SpinnerLabel_3              matlab.ui.control.Label
        y2Spinner_M                   matlab.ui.control.Spinner
        y2SpinnerLabel_3              matlab.ui.control.Label
        x2Spinner_M                   matlab.ui.control.Spinner
        x2SpinnerLabel_3              matlab.ui.control.Label
        y1Spinner_M                   matlab.ui.control.Spinner
        y1SpinnerLabel_3              matlab.ui.control.Label
        x1Spinner_M                   matlab.ui.control.Spinner
        x1Spinner_2Label_2            matlab.ui.control.Label
        BrowseButton_M_linear         matlab.ui.control.Button
        MmeasuredEditField            matlab.ui.control.EditField
        MmeasuredEditFieldLabel       matlab.ui.control.Label
        standardfieldPanel            matlab.ui.container.Panel
        DescriptionTextArea           matlab.ui.control.TextArea
        DescriptionTextAreaLabel      matlab.ui.control.Label
        startbuildingstandardfieldButton  matlab.ui.control.Button
        plottheconfigurationCheckBox  matlab.ui.control.CheckBox
        ztargetmthemaximumzofsimulationSpinner  matlab.ui.control.Spinner
        ztargetmthemaximumzofsimulationLabel  matlab.ui.control.Label
        importdataPanel               matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        igorh5Tab                     matlab.ui.container.Tab
        thetaydeltaEditField          matlab.ui.control.NumericEditField
        thetaydeltaEditFieldLabel     matlab.ui.control.Label
        thetayoffsetEditField         matlab.ui.control.NumericEditField
        thetayoffsetEditFieldLabel    matlab.ui.control.Label
        thetaxoffsetEditField         matlab.ui.control.NumericEditField
        thetaxoffsetEditFieldLabel    matlab.ui.control.Label
        thetaxdeltaEditField          matlab.ui.control.NumericEditField
        thetaxdeltaEditFieldLabel     matlab.ui.control.Label
        energydeltaEditField          matlab.ui.control.NumericEditField
        energydeltaEditFieldLabel     matlab.ui.control.Label
        energyoffsetEditField         matlab.ui.control.NumericEditField
        energyoffsetEditFieldLabel    matlab.ui.control.Label
        thetaynumEditField            matlab.ui.control.NumericEditField
        thetaynumLabel                matlab.ui.control.Label
        thetaxnumEditField            matlab.ui.control.NumericEditField
        thetaxnumLabel                matlab.ui.control.Label
        energynumEditField            matlab.ui.control.NumericEditField
        energynumLabel                matlab.ui.control.Label
        EfeVSpinner                   matlab.ui.control.Spinner
        EfeVSpinnerLabel              matlab.ui.control.Label
        readdataButton                matlab.ui.control.Button
        outputBrowseButton            matlab.ui.control.Button
        outputpathEditField           matlab.ui.control.EditField
        outputpathEditFieldLabel      matlab.ui.control.Label
        dataBrowseButton              matlab.ui.control.Button
        datatypeDropDown              matlab.ui.control.DropDown
        datatypeDropDownLabel         matlab.ui.control.Label
        datapathEditField             matlab.ui.control.EditField
        datapathEditFieldLabel        matlab.ui.control.Label
        ModesButtonGroup              matlab.ui.container.ButtonGroup
        modesDropDown                 matlab.ui.control.DropDown
        modesDropDownLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        meandeltaLabel                matlab.ui.control.Label
        slit_demo                     matlab.ui.control.Image
        BcenterLabel                  matlab.ui.control.Label
        manipulator_demo              matlab.ui.control.Image
        k_rAA1Spinner                 matlab.ui.control.Spinner
        k_rradiusofthecentralareabeingplottedLabel  matlab.ui.control.Label
        UIAxes_geometry               matlab.ui.control.UIAxes
        UIAxes_band                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        bands_P
        bands_M
        bands_O
        filepath_P
        filepath_M
        filepath_O
        standard_field
        geometry
        transform
        CircleHandle1 matlab.graphics.chart.primitive.Line
        TextHandle1 matlab.graphics.primitive.Text
        CircleHandle2 matlab.graphics.chart.primitive.Line
        TextHandle2 matlab.graphics.primitive.Text
        CircleHandle3 matlab.graphics.chart.primitive.Line
        TextHandle3 matlab.graphics.primitive.Text
        CircleHandle4 matlab.graphics.chart.primitive.Line
        TextHandle4 matlab.graphics.primitive.Text
        CircleHandle5 matlab.graphics.chart.primitive.Line
        TextHandle5 matlab.graphics.primitive.Text
        CircleHandle6 matlab.graphics.chart.primitive.Line
        TextHandle6 matlab.graphics.primitive.Text
        CircleHandle7 matlab.graphics.chart.primitive.Line
        TextHandle7 matlab.graphics.primitive.Text
    end
    
    methods (Access = private)
        function corrected_diff_app(app, k_r, corrected_plus, corrected_minus)
            % k_r=1.5
            kx_plus = corrected_plus.kx;
            ky_plus = corrected_plus.ky;
            try
                I_plus = corrected_plus.I;
            catch
                I_plus = corrected_plus.I_thetax_thetay;
            end
            kx_minus = corrected_minus.kx;
            ky_minus = corrected_minus.ky;
            try
                I_minus = corrected_minus.I;
            catch 
                I_minus = corrected_minus.I_thetax_thetay;
            end
            if ~isa(I_plus, 'double') 
                I_plus = double(I_plus);
            end
            if ~isa(I_minus, 'double')
                I_minus = double(I_minus);
            end
        
            center_mask_plus = (kx_plus.^2 + ky_plus.^2 < k_r^2);
            kx_plus_ROI = kx_plus(center_mask_plus);
            ky_plus_ROI = ky_plus(center_mask_plus);
            I_plus_ROI = I_plus(center_mask_plus);
            
            F_minus = scatteredInterpolant(kx_minus(:),ky_minus(:),I_minus(:),'linear','none');
            I_minus_ROI_intp = F_minus(kx_plus_ROI, ky_plus_ROI);
            clean_mask = ~isnan(I_minus_ROI_intp) & ~isnan(I_plus_ROI);

            vals = I_plus_ROI(clean_mask)-I_minus_ROI_intp(clean_mask);
            scatter(app.UIAxes_band, kx_plus_ROI(clean_mask), ky_plus_ROI(clean_mask), 10, vals,'filled');
            axis(app.UIAxes_band, 'equal', 'tight');
            xlabel(app.UIAxes_band, 'k_x [10^{10} m^{-1}]');
            ylabel(app.UIAxes_band, 'k_y [10^{10} m^{-1}]');
            title(app.UIAxes_band, 'Difference between corrected +field and -field fermi surface');
            % Build a smooth colormap
            n = 256; % number of steps in the gradient
            blue_to_white = [linspace(0,1,n/2)' linspace(0,1,n/2)' ones(n/2,1)];
            white_to_red   = [ones(n/2,1) linspace(1,0,n/2)' linspace(1,0,n/2)'];
            cmap = [blue_to_white; white_to_red];
            colormap(app.UIAxes_band, cmap);
            clim(app.UIAxes_band,[-max(abs(vals)) max(abs(vals))]);
            colorbar(app.UIAxes_band);  

            % print similarity index
            mean_delta = mean(abs(vals));
            app.meandeltaLabel.Text = sprintf('mean delta = %.4f', mean_delta);
        end

        function plot_geometry(app)
            gm = app.geometry;
            thetaZ = - app.transform.thetaZ;
            thetaY = - app.transform.thetaY;
            thetaX = - app.transform.thetaX;

            % rotate CW around Y,X,Z in order, axes being attached to the geometry! 
            % So the order is inversed!
            gm = rotate(gm, thetaZ, [0,0,0], [0,0,1]);
            gm = rotate(gm, thetaX, [0,0,0], [1,0,0]);
            gm = rotate(gm, thetaY, [0,0,0], [0,1,0]);
            gm = translate(gm, [app.transform.transX, app.transform.transY, app.transform.transZ]);

            pdegplot(gm, 'Parent', app.UIAxes_geometry);
            view(app.UIAxes_geometry, 3); 
            xlim(app.UIAxes_geometry, [-2e-3 2e-3]); % X-axis window
            ylim(app.UIAxes_geometry, [-2e-3 2e-3]); % Y-axis window
            zlim(app.UIAxes_geometry, [-1e-4 1e-4]); % Z-axis window
            axis(app.UIAxes_geometry, 'tight'); % Fit axes to geometry bounds
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Geometry Demo for correction mode
            % Geometry parameters
            coilDin       = 6.3e-3;
            coilDout      = 12e-3;
            coilHeight    = 5.6e-3;
            shieldDout    = 13e-3;
            shieldDin     = 4e-3;
            shieldHeight  = 0.25e-3;
            coreDout1     = 5.334e-3;
            coreDin       = 2.286e-3;
            coreDout2     = 3.5e-3;
            coreHeight1   = 2.794e-3;
            coreHeight2   = 3.048e-3;
            gapCoilCore   = 1.28e-3;
            gapCoilShield = 2e-4;
            grooveLength  = 1.016e-3;
        
            % No rotation/translation (standard configuration)
            app.transform.transX=0;
            app.transform.transY=0;
            app.transform.transZ=0;
            app.transform.thetaX=0;
            app.transform.thetaY=0;
            app.transform.thetaZ=0;
            
            % Build geometry
            shieldGm = multicylinder([shieldDin/2, shieldDout/2], shieldHeight, Void=[1 0]);
            shieldGm = translate(shieldGm, [0,0,-shieldHeight + gapCoilShield]);
        
            coilGm = multicylinder([coilDin/2, coilDout/2], coilHeight, Void=[1 0]);
            coilGm = translate(coilGm, [0,0,-shieldHeight-coilHeight]);
                
            coreGm1 = multicylinder([coreDin/2, coreDout1/2], coreHeight1, Void=[1 0]);
            coreGm2 = multicylinder([coreDin/2, coreDout2/2], coreHeight2, Void=[1 0]);
            coreGm1 = translate(coreGm1, [0,0,-shieldHeight-coreHeight1-gapCoilCore]);
            coreGm2 = translate(coreGm2, [0,0,-shieldHeight-coreHeight1-coreHeight2-gapCoilCore+2e-5]);
            % 2e-5 is to make the cores overlapped so that later we can combine
            % them
            cuttingBox = multicuboid(grooveLength, coreDout1, grooveLength);
            cuttingBox = translate(cuttingBox, [0, 0, -shieldHeight-gapCoilCore-grooveLength/2]);
        
            coreGm1 = fegeometry(coreGm1);
            coreGm2 = fegeometry(coreGm2);
            shieldGm = fegeometry(shieldGm);
            coilGm   = fegeometry(coilGm);
            cuttingBox = fegeometry(cuttingBox);
            groovedCoreGm = subtract(union(coreGm1, coreGm2), cuttingBox);
        
            % collect all the geometry
            gm = union(groovedCoreGm, coilGm);
            gm = union(gm, shieldGm);
            app.geometry = gm;

            pdegplot(app.geometry, 'Parent', app.UIAxes_geometry);
            view(app.UIAxes_geometry, 3); % [azimuth elevation] vector
            % Set custom limits
            xlim(app.UIAxes_geometry, [-2e-3 2e-3]); % X-axis window
            ylim(app.UIAxes_geometry, [-2e-3 2e-3]); % Y-axis window
            zlim(app.UIAxes_geometry, [-1e-4 1e-4]); % Z-axis window
            axis(app.UIAxes_geometry, 'tight'); % Fit axes to geometry bounds
        end

        % Button pushed function: viewcorrectionButton
        function viewcorrectionButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please Wait', ...
                'Message','Running analysis...', ...
                'Indeterminate','on');
            parameters.transX = app.transXSpinner.Value;
            parameters.transY = app.transYSpinner.Value;
            parameters.transZ = app.transZSpinner.Value;
            parameters.thetaX = app.thetaXSpinner.Value*pi/180;
            parameters.thetaY = app.thetaYSpinner.Value*pi/180;
            parameters.thetaZ = app.thetaZSpinner.Value*pi/180;
            parameters.current = app.currentASpinner.Value;
            energy = app.EnergyeVEditField.Value;
            delta_E = app.DeltaEmeVEditField.Value / 1000;  % notice meV
            k_r = app.k_rAA1Spinner.Value;
            if isempty(app.PmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of P measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.MmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of M measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.standard_field)
                try 
                    app.standard_field = load('./matdata/standard_field_withShield.mat'); 
                    % This filename corresponds to the file saved in the function calc_standard_field (outside this app)
                catch 
                    uialert(app.UIFigure, ...
                        'Please make sure there is standard_field.mat under the current directory',...
                        'Error');
                    return;
                end
            end
            if ~strcmp(app.filepath_P, string(app.PmeasuredTextArea.Value))
                app.filepath_P = string(app.PmeasuredTextArea.Value);
                app.bands_P = load(string(app.PmeasuredTextArea.Value));
            end
            if ~strcmp(app.filepath_M, string(app.MmeasuredTextArea.Value))
                app.filepath_M = string(app.MmeasuredTextArea.Value);
                app.bands_M = load(string(app.MmeasuredTextArea.Value));
            end
            idx_P = find(app.bands_P.E >= energy - delta_E/2 & app.bands_P.E <= energy + delta_E/2);  % this is a list of indices
            idx_M = find(app.bands_M.E >= energy - delta_E/2 & app.bands_M.E <= energy + delta_E/2);
            idx_P_middle = idx_P(ceil(numel(idx_P)/2));
            idx_M_middle = idx_M(ceil(numel(idx_M)/2));
            fs_P.kx = squeeze(app.bands_P.kx(idx_P_middle, :, :));
            fs_P.ky = squeeze(app.bands_P.ky(idx_P_middle, :, :));
            fs_P.I_thetax_thetay = squeeze(mean(app.bands_P.I_thetax_thetay(idx_P, :, :), 1));
            fs_M.kx = squeeze(app.bands_M.kx(idx_M_middle, :, :));
            fs_M.ky = squeeze(app.bands_M.ky(idx_M_middle, :, :));
            fs_M.I_thetax_thetay = squeeze(mean(app.bands_M.I_thetax_thetay(idx_M, :, :), 1));
            
            [Fkx_plus, Fky_plus] = calc_inverse_mapping(app.standard_field, energy, parameters, false);% angles in rad
            parameters.current = - parameters.current;
            [Fkx_minus, Fky_minus] = calc_inverse_mapping(app.standard_field, energy, parameters, false);
            
            corrected_plus = backward_mapping(fs_P, Fkx_plus, Fky_plus, false);   % .kx .ky .I are vectors
            corrected_minus = backward_mapping(fs_M, Fkx_minus, Fky_minus, false);% .kx .ky .I are vectors
            corrected_diff_app(app, k_r, corrected_plus, corrected_minus);
            % show the field at the center given the input parameters
            parameters.current = - parameters.current;
            center_field = 1000 * calc_center_field(app.standard_field, parameters, [0,0,0]);  % 3-vector of the field at the center
            output_str = sprintf('B(center) (mT) = (%.2f, %.2f, %.2f)', center_field(1), center_field(2), center_field(3));
            app.BcenterLabel.Text = output_str;
            close(d);
        end

        % Button pushed function: BrowseButton_P
        function BrowseButton_PPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the .mat data file of plus field measured bands');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.PmeasuredTextArea.Value = fullPath;
            end
        end

        % Button pushed function: BrowseButton_M
        function BrowseButton_MPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the .mat data file of minus field measured bands');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.MmeasuredTextArea.Value = fullPath;
            end
        end

        % Selection changed function: ModesButtonGroup
        function ModesButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: startbuildingstandardfieldButton
        function startbuildingstandardfieldButtonPushed(app, event)
            calc_standard_field(app.ztargetmthemaximumzofsimulationSpinner.Value, app.plottheconfigurationCheckBox.Value);
        end

        % Value changed function: currentASpinner
        function currentASpinnerValueChanged(app, event)
            value = app.currentASpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.currentASpinner.Step = step;
        end

        % Value changed function: thetaZSpinner
        function thetaZSpinnerValueChanged(app, event)
            value = app.thetaZSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.thetaZSpinner.Step = step;
            app.transform.thetaZ = value;
            plot_geometry(app);
        end

        % Value changed function: thetaYSpinner
        function thetaYSpinnerValueChanged(app, event)
            value = app.thetaYSpinner.Value;
            step = max(0.01*abs(value),1d-8);
            app.thetaYSpinner.Step = step;
            app.transform.thetaY = value;
            plot_geometry(app);
        end

        % Value changed function: thetaXSpinner
        function thetaXSpinnerValueChanged(app, event)
            value = app.thetaXSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.thetaXSpinner.Step = step;
            app.transform.thetaX = value;
            plot_geometry(app);
        end

        % Value changed function: transZSpinner
        function transZSpinnerValueChanged(app, event)
            value = app.transZSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.transZSpinner.Step = step;
            app.transform.transZ = value;
            plot_geometry(app);
        end

        % Value changed function: transYSpinner
        function transYSpinnerValueChanged(app, event)
            value = app.transYSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.transYSpinner.Step = step;
            app.transform.transY = value;
            plot_geometry(app);
        end

        % Value changed function: transXSpinner
        function transXSpinnerValueChanged(app, event)
            value = app.transXSpinner.Value;
            step = max(0.01*abs(value),1e-8);
            app.transXSpinner.Step = step;
            app.transform.transX = value;
            plot_geometry(app);
        end

        % Image clicked function: manipulator_demo
        function manipulator_demoClicked(app, event)
            I = imread('./figures/manipulator_demo.png');
            figure; imshow(I);
        end

        % Image clicked function: slit_demo
        function slit_demoImageClicked(app, event)
            I = imread('./figures/slit_demo.png');
            figure; imshow(I);
        end

        % Button pushed function: dataBrowseButton
        function dataBrowseButtonPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the .h5 file generated by Igor');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.datapathEditField.Value = fullPath;   % char
            end
        end

        % Button pushed function: outputBrowseButton
        function outputBrowseButtonPushed(app, event)
            folderPath = uigetdir(pwd, 'Choose the folder to hold the corresponding .mat file');
            if folderPath ~= 0
                app.outputpathEditField.Value = folderPath;
            end
        end

        % Value changed function: datatypeDropDown
        function datatypeDropDownValueChanged(app, event)
            value = app.datatypeDropDown.Value;
            switch string(value)
                case "raw .h5"
                    app.TabGroup.Visible = 'off';
                case "Igor .h5"
                    app.TabGroup.Visible = 'on';
                case "to be implemented"
                    error('this part is unavailable right now');
            end
        end

        % Button pushed function: readdataButton
        function readdataButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please Wait', ...
                'Message','Running analysis...', ...
                'Indeterminate','on');
            [~, filename, ~] = fileparts(string(app.datapathEditField.Value));
            switch string(app.datatypeDropDown.Value)
                case "raw .h5"
                    read_h5(string(app.datapathEditField.Value), fullfile(app.outputpathEditField.Value, filename+".mat"));
                case "Igor .h5"
                    read_igor_h5(app.datapathEditField.Value, fullfile(app.outputpathEditField.Value, filename+".mat"), ...
                        app.energynumEditField.Value, app.thetaxnumEditField.Value, app.thetaynumEditField.Value, ...
                        app.energydeltaEditField.Value, app.thetaxdeltaEditField.Value, app.thetaydeltaEditField.Value, ...
                        app.energyoffsetEditField.Value, app.thetaxoffsetEditField.Value, app.thetayoffsetEditField.Value, ...
                        app.EfeVSpinner.Value);
            end
            close(d);
        end

        % Button pushed function: BrowseButton_export
        function BrowseButton_exportPushed(app, event)
            folderPath = uigetdir(pwd, 'Choose the folder to hold two corrected data as .bin files');
            if folderPath ~= 0
                app.exportdirEditField.Value = folderPath;
            end
        end

        % Button pushed function: correctallButton
        function correctallButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please Wait', ...
            'Message','Starting analysis...', ...
            'Indeterminate','off', ...
            'Value',0);


            [~, filename_P, ~] = fileparts(app.PmeasuredTextArea.Value);
            [~, filename_M, ~] = fileparts(app.MmeasuredTextArea.Value);

            parameters.transX = app.transXSpinner.Value;
            parameters.transY = app.transYSpinner.Value;
            parameters.transZ = app.transZSpinner.Value;
            parameters.thetaX = app.thetaXSpinner.Value;  % use degree!
            parameters.thetaY = app.thetaYSpinner.Value;
            parameters.thetaZ = app.thetaZSpinner.Value;
            parameters.current = app.currentASpinner.Value;

            if isempty(app.PmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of P measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.MmeasuredTextArea.Value)
                uialert(app.UIFigure, ...
                    'Please first assign the path of M measured', ...   % content
                    'Error');                                % title
                return;
            end
            if isempty(app.standard_field)
                try 
                    app.standard_field = load('./matdata/standard_field_withShield.mat');
                catch 
                    uialert(app.UIFigure, ...
                        'Please make sure there is standard_field.mat under the current directory',...
                        'Error');
                    return;
                end
            end
            if ~strcmp(app.filepath_P, string(app.PmeasuredTextArea.Value))
                app.filepath_P = string(app.PmeasuredTextArea.Value);
                app.bands_P = load(string(app.PmeasuredTextArea.Value));
            end
            if ~strcmp(app.filepath_M, string(app.MmeasuredTextArea.Value))
                app.filepath_M = string(app.MmeasuredTextArea.Value);
                app.bands_M = load(string(app.MmeasuredTextArea.Value));
            end


            if strcmp(app.anglemapSwitch.Value, "Off")
                % angles in degree
                build_bin(fullfile(app.exportdirEditField.Value, filename_P+"_Corrected.bin"), app.bands_P, app.standard_field, parameters, ...
                    app.thetaxoffsetEditField.Value, app.thetaxdeltaEditField.Value, app.thetaxnumEditField.Value, ...
                     app.thetayoffsetEditField.Value, app.thetaydeltaEditField.Value, app.thetaynumEditField.Value);
                d.Value = 0.5;          % halfway done
                d.Message = 'P bands correction finished. Running M bands correction...';
                drawnow;                % force UI update
    
                parameters.current = -parameters.current;
                build_bin(fullfile(app.exportdirEditField.Value, filename_M+"_Corrected.bin"), app.bands_M, app.standard_field, parameters, ...
                    app.thetaxoffsetEditField.Value, app.thetaxdeltaEditField.Value, app.thetaxnumEditField.Value, ...
                     app.thetayoffsetEditField.Value, app.thetaydeltaEditField.Value, app.thetaynumEditField.Value);
                close(d);
            else
                build_bin_angle(fullfile(app.exportdirEditField.Value, filename_P+"_Corrected.bin"), app.bands_P, app.standard_field, parameters, ...
                    app.thetaxoffsetEditField.Value, app.thetaxdeltaEditField.Value, app.thetaxnumEditField.Value, ...
                     app.thetayoffsetEditField.Value, app.thetaydeltaEditField.Value, app.thetaynumEditField.Value, ...
                     app.EnergyeVEditField.Value);
                d.Value = 0.5;
                d.Message = 'P bands correction finished. Running M bands correction...';
                drawnow;

                parameters.current = -parameters.current;
                build_bin_angle(fullfile(app.exportdirEditField.Value, filename_M+"_Corrected.bin"), app.bands_M, app.standard_field, parameters, ...
                    app.thetaxoffsetEditField.Value, app.thetaxdeltaEditField.Value, app.thetaxnumEditField.Value, ...
                     app.thetayoffsetEditField.Value, app.thetaydeltaEditField.Value, app.thetaynumEditField.Value, ...
                     app.EnergyeVEditField.Value);
                close(d);
            end
        end

        % Value changed function: modesDropDown
        function modesDropDownValueChanged(app, event)
            switch string(app.modesDropDown.Value)
                case "standard field"
                    app.standardfieldPanel.Visible = 'on';
                    app.importdataPanel.Visible = 'off';
                    app.correctionPanel.Visible = 'off';
                    app.UIAxes_geometry.Visible = 'on';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'off';
                    app.correctallPanel.Visible = 'off';
                    app.lineardistortionPanel.Visible = 'off';
                case "correction"
                    app.standardfieldPanel.Visible = 'off';
                    app.importdataPanel.Visible = 'off';
                    app.correctionPanel.Visible = 'on';
                    app.UIAxes_geometry.Visible = 'on';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'off';
                    app.correctallPanel.Visible = 'off';
                    app.lineardistortionPanel.Visible = 'off';
                case "import data"
                    app.standardfieldPanel.Visible = 'off';
                    app.importdataPanel.Visible = 'on';
                    app.correctionPanel.Visible = 'off';
                    app.UIAxes_geometry.Visible = 'off';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'on';
                    app.correctallPanel.Visible = 'off';
                    app.lineardistortionPanel.Visible = 'off';
                case "correct all"
                    app.standardfieldPanel.Visible = 'off';
                    app.importdataPanel.Visible = 'off';
                    app.correctionPanel.Visible = 'off';
                    app.UIAxes_geometry.Visible = 'on';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'off';
                    app.correctallPanel.Visible = 'on';
                    app.lineardistortionPanel.Visible = 'off';
                case "linear distortion"
                    app.standardfieldPanel.Visible = 'off';
                    app.importdataPanel.Visible = 'off';
                    app.correctionPanel.Visible = 'off';
                    app.UIAxes_geometry.Visible = 'on';
                    app.manipulator_demo.Visible = 'on';
                    app.slit_demo.Visible = 'off';
                    app.correctallPanel.Visible = 'off';
                    app.lineardistortionPanel.Visible = 'on';
            end
        end

        % Button pushed function: previewButton_O
        function previewButton_OPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please Wait', ...
            'Message','Starting analysis...', ...
            'Indeterminate','off', ...
            'Value',0);

            cla(app.UIAxes_band);
            if ~strcmp(app.filepath_O, string(app.OmeasuredEditField.Value))
                app.filepath_O = string(app.OmeasuredEditField.Value);
                app.bands_O = load(string(app.OmeasuredEditField.Value));
            end
            energy = app.energyeVSpinner_O.Value;
            [~, idx] = min(abs(app.bands_O.E-energy));  % this is a list of indices
            I_fermi = squeeze(mean(app.bands_O.I_thetax_thetay(idx-2:idx+2, :, :), 1));
            scatter(app.UIAxes_band, app.bands_O.thetax(:), app.bands_O.thetay(:), [], I_fermi(:), 'filled');
            axis(app.UIAxes_band, 'equal', 'tight');
            xlabel(app.UIAxes_band, 'thetax [deg]');
            ylabel(app.UIAxes_band, 'thetay [deg]');
            title(app.UIAxes_band, 'fermi surface of 0-field to get the high-symmetry points');
            colormap(app.UIAxes_band, turbo); colorbar(app.UIAxes_band); 
            hold(app.UIAxes_band, 'on');

            app.CircleHandle1 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "1"
            app.TextHandle1 = text(app.UIAxes_band, 0, 0, '1', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle2 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "2"
            app.TextHandle2 = text(app.UIAxes_band, 0, 0, '2', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle3 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "3"
            app.TextHandle3 = text(app.UIAxes_band, 0, 0, '3', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle4 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "4"
            app.TextHandle4 = text(app.UIAxes_band, 0, 0, '4', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle5 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "5"
            app.TextHandle5 = text(app.UIAxes_band, 0, 0, '5', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle6 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "6"
            app.TextHandle6 = text(app.UIAxes_band, 0, 0, '6', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle7 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "7"
            app.TextHandle7 = text(app.UIAxes_band, 0, 0, '7', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');

            close(d);
        end

        % Button pushed function: BrowseButton_M_linear
        function BrowseButton_M_linearPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the - field .mat file generated by importing');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.MmeasuredEditField.Value = fullPath;   % char
            end
        end

        % Button pushed function: BrowseButton_P_linear
        function BrowseButton_P_linearPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the + field .mat file generated by importing');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.PmeasuredEditField.Value = fullPath;   % char
            end
        end

        % Button pushed function: BrowseButton_O_linear
        function BrowseButton_O_linearPushed(app, event)
            [fileName, filePath] = uigetfile(pwd, 'Choose the 0 field .mat file generated by importing');
            if filePath ~= 0
                fullPath = fullfile(filePath, fileName);
                app.OmeasuredEditField.Value = fullPath;   % char
            end
        end

        % Value changed function: x1Spinner_M, x1Spinner_O, x1Spinner_P
        function x1Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle1.XData = value;
            app.TextHandle1.Position = [app.CircleHandle1.XData, app.CircleHandle1.YData, 0];
        end

        % Value changed function: y1Spinner_M, y1Spinner_O, y1Spinner_P
        function y1Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle1.YData = value;
            app.TextHandle1.Position = [app.CircleHandle1.XData, app.CircleHandle1.YData, 0];
        end

        % Value changed function: x2Spinner_M, x2Spinner_O, x2Spinner_P
        function x2Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle2.XData = value;
            app.TextHandle2.Position = [app.CircleHandle2.XData, app.CircleHandle2.YData, 0];
        end

        % Value changed function: y2Spinner_M, y2Spinner_O, y2Spinner_P
        function y2Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle2.YData = value;
            app.TextHandle2.Position = [app.CircleHandle2.XData, app.CircleHandle2.YData, 0];
        end

        % Value changed function: y3Spinner_M, y3Spinner_O, y3Spinner_P
        function y3Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle3.YData = value;
            app.TextHandle3.Position = [app.CircleHandle3.XData, app.CircleHandle3.YData, 0];
        end

        % Value changed function: x3Spinner_M, x3Spinner_O, x3Spinner_P
        function x3Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle3.XData = value;
            app.TextHandle3.Position = [app.CircleHandle3.XData, app.CircleHandle3.YData, 0];
        end

        % Value changed function: x4Spinner_M, x4Spinner_O, x4Spinner_P
        function x4Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle4.XData = value;
            app.TextHandle4.Position = [app.CircleHandle4.XData, app.CircleHandle4.YData, 0];
        end

        % Value changed function: y4Spinner_M, y4Spinner_O, y4Spinner_P
        function y4Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle4.YData = value;
            app.TextHandle4.Position = [app.CircleHandle4.XData, app.CircleHandle4.YData, 0];
        end

        % Value changed function: x5Spinner_M, x5Spinner_O, x5Spinner_P
        function x5Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle5.XData = value;
            app.TextHandle5.Position = [app.CircleHandle5.XData, app.CircleHandle5.YData, 0];
        end

        % Value changed function: y5Spinner_M, y5Spinner_O, y5Spinner_P
        function y5Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle5.YData = value;
            app.TextHandle5.Position = [app.CircleHandle5.XData, app.CircleHandle5.YData, 0];
        end

        % Value changed function: y6Spinner_M, y6Spinner_O, y6Spinner_P
        function y6Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle6.YData = value;
            app.TextHandle6.Position = [app.CircleHandle6.XData, app.CircleHandle6.YData, 0];
        end

        % Value changed function: x6Spinner_M, x6Spinner_O, x6Spinner_P
        function x6Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle6.XData = value;
            app.TextHandle6.Position = [app.CircleHandle6.XData, app.CircleHandle6.YData, 0];
        end

        % Value changed function: x7Spinner_M, x7Spinner_O, x7Spinner_P
        function x7Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle7.XData = value;
            app.TextHandle7.Position = [app.CircleHandle7.XData, app.CircleHandle7.YData, 0];
        end

        % Value changed function: y7Spinner_M, y7Spinner_O, y7Spinner_P
        function y7Spinner_OValueChanged(app, event)
            sourceSpinner = event.Source;
            % Get the new value from the Spinner that was just changed
            value = sourceSpinner.Value;
            % Update the Step property (logic for all Spinners)
            step = max(0.01 * abs(value), 1e-5);
            sourceSpinner.Step = step;
            app.CircleHandle7.YData = value;
            app.TextHandle7.Position = [app.CircleHandle7.XData, app.CircleHandle7.YData, 0];
        end

        % Button pushed function: previewButton_P
        function previewButton_PPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please Wait', ...
            'Message','Starting analysis...', ...
            'Indeterminate','off', ...
            'Value',0);

            cla(app.UIAxes_band);
            if ~strcmp(app.filepath_P, string(app.PmeasuredEditField.Value))
                app.filepath_P = string(app.PmeasuredEditField.Value);
                app.bands_P = load(string(app.PmeasuredEditField.Value));
            end
            energy = app.energyeVSpinner_P.Value;
            [~, idx] = min(abs(app.bands_P.E-energy));  % this is a list of indices
            I_fermi = squeeze(mean(app.bands_P.I_thetax_thetay(idx-2:idx+2, :, :), 1));
            scatter(app.UIAxes_band, app.bands_P.thetax(:), app.bands_P.thetay(:), [], I_fermi(:), 'filled');
            axis(app.UIAxes_band, 'equal', 'tight');
            xlabel(app.UIAxes_band, 'thetax [deg]');
            ylabel(app.UIAxes_band, 'thetay [deg]');
            title(app.UIAxes_band, 'fermi surface of 0-field to get the high-symmetry points');
            colormap(app.UIAxes_band, turbo); colorbar(app.UIAxes_band); 
            hold(app.UIAxes_band, 'on');

            app.CircleHandle1 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "1"
            app.TextHandle1 = text(app.UIAxes_band, 0, 0, '1', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle2 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "2"
            app.TextHandle2 = text(app.UIAxes_band, 0, 0, '2', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle3 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "3"
            app.TextHandle3 = text(app.UIAxes_band, 0, 0, '3', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle4 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "4"
            app.TextHandle4 = text(app.UIAxes_band, 0, 0, '4', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle5 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "5"
            app.TextHandle5 = text(app.UIAxes_band, 0, 0, '5', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle6 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "6"
            app.TextHandle6 = text(app.UIAxes_band, 0, 0, '6', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle7 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "7"
            app.TextHandle7 = text(app.UIAxes_band, 0, 0, '7', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');

            close(d);
        end

        % Button pushed function: previewButton_M
        function previewButton_MPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please Wait', ...
            'Message','Starting analysis...', ...
            'Indeterminate','off', ...
            'Value',0);

            cla(app.UIAxes_band);
            if ~strcmp(app.filepath_M, string(app.MmeasuredEditField.Value))
                app.filepath_M = string(app.MmeasuredEditField.Value);
                app.bands_M = load(string(app.MmeasuredEditField.Value));
            end
            energy = app.energyeVSpinner_M.Value;
            [~, idx] = min(abs(app.bands_M.E-energy));  % this is a list of indices
            I_fermi = squeeze(mean(app.bands_M.I_thetax_thetay(idx-2:idx+2, :, :), 1));
            scatter(app.UIAxes_band, app.bands_M.thetax(:), app.bands_M.thetay(:), [], I_fermi(:), 'filled');
            axis(app.UIAxes_band, 'equal', 'tight');
            xlabel(app.UIAxes_band, 'thetax [deg]');
            ylabel(app.UIAxes_band, 'thetay [deg]');
            title(app.UIAxes_band, 'fermi surface of 0-field to get the high-symmetry points');
            colormap(app.UIAxes_band, turbo); colorbar(app.UIAxes_band); 
            hold(app.UIAxes_band, 'on');

            app.CircleHandle1 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "1"
            app.TextHandle1 = text(app.UIAxes_band, 0, 0, '1', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle2 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "2"
            app.TextHandle2 = text(app.UIAxes_band, 0, 0, '2', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle3 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "3"
            app.TextHandle3 = text(app.UIAxes_band, 0, 0, '3', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle4 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "4"
            app.TextHandle4 = text(app.UIAxes_band, 0, 0, '4', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle5 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "5"
            app.TextHandle5 = text(app.UIAxes_band, 0, 0, '5', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle6 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "6"
            app.TextHandle6 = text(app.UIAxes_band, 0, 0, '6', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');
            app.CircleHandle7 = plot(app.UIAxes_band, 0, 0, 'ro', ...
                'MarkerSize', 3, 'LineWidth', 0.5);
            % Add text label "7"
            app.TextHandle7 = text(app.UIAxes_band, 0, 0, '7', ...
                'VerticalAlignment','bottom', ...
                'HorizontalAlignment','right', ...
                'FontWeight','bold');

            close(d);
        end

        % Button pushed function: exportBrowseButton_P_linear
        function exportBrowseButton_P_linearPushed(app, event)
            folderPath = uigetdir(pwd, 'Choose the folder to hold the corresponding .mat file');
            if folderPath ~= 0
                app.exportdirEditField_P_linear.Value = folderPath;
            end
        end

        % Button pushed function: exportBrowseButton_M_linear
        function exportBrowseButton_M_linearPushed(app, event)
            folderPath = uigetdir(pwd, 'Choose the folder to hold the corresponding .mat file');
            if folderPath ~= 0
                app.exportdirEditField_M_linear.Value = folderPath;
            end
        end

        % Button pushed function: linearcorrectionButton_M
        function linearcorrectionButton_MPushed(app, event)
            [~, filename, ~] = fileparts(string(app.MmeasuredEditField.Value));
            outPath = fullfile(string(app.exportdirEditField_M_linear.Value), string(filename)+"_linear_corrected.bin");
            
            if ~strcmp(app.filepath_M, string(app.MmeasuredEditField.Value))
                app.filepath_M = string(app.MmeasuredEditField.Value);
                app.bands_M = load(string(app.MmeasuredEditField.Value));
            end

            movingPoints = [app.x1Spinner_M.Value, app.y1Spinner_M.Value;
                app.x2Spinner_M.Value, app.y2Spinner_M.Value;
                app.x3Spinner_M.Value, app.y3Spinner_M.Value;
                app.x4Spinner_M.Value, app.y4Spinner_M.Value;
                app.x5Spinner_M.Value, app.y5Spinner_M.Value;
                app.x6Spinner_M.Value, app.y6Spinner_M.Value;
                app.x7Spinner_M.Value, app.y7Spinner_M.Value];
            fixedPoints = [app.x1Spinner_O.Value, app.y1Spinner_O.Value;
                app.x2Spinner_O.Value, app.y2Spinner_O.Value;
                app.x3Spinner_O.Value, app.y3Spinner_O.Value;
                app.x4Spinner_O.Value, app.y4Spinner_O.Value;
                app.x5Spinner_O.Value, app.y5Spinner_O.Value;
                app.x6Spinner_O.Value, app.y6Spinner_O.Value;
                app.x7Spinner_O.Value, app.y7Spinner_O.Value];
            build_bin_linear(movingPoints,fixedPoints, app.bands_M, outPath, false);
        end

        % Button pushed function: linearcorrectionButton_P
        function linearcorrectionButton_PPushed(app, event)
            [~, filename, ~] = fileparts(string(app.PmeasuredEditField.Value));
            outPath = fullfile(string(app.exportdirEditField_P_linear.Value), string(filename)+"_linear_corrected.bin");
            
            if ~strcmp(app.filepath_P, string(app.PmeasuredEditField.Value))
                app.filepath_P = string(app.PmeasuredEditField.Value);
                app.bands_P = load(string(app.PmeasuredEditField.Value));
            end

            movingPoints = [app.x1Spinner_P.Value, app.y1Spinner_P.Value;
                app.x2Spinner_P.Value, app.y2Spinner_P.Value;
                app.x3Spinner_P.Value, app.y3Spinner_P.Value;
                app.x4Spinner_P.Value, app.y4Spinner_P.Value;
                app.x5Spinner_P.Value, app.y5Spinner_P.Value;
                app.x6Spinner_P.Value, app.y6Spinner_P.Value;
                app.x7Spinner_P.Value, app.y7Spinner_P.Value];
            fixedPoints = [app.x1Spinner_O.Value, app.y1Spinner_O.Value;
                app.x2Spinner_O.Value, app.y2Spinner_O.Value;
                app.x3Spinner_O.Value, app.y3Spinner_O.Value;
                app.x4Spinner_O.Value, app.y4Spinner_O.Value;
                app.x5Spinner_O.Value, app.y5Spinner_O.Value;
                app.x6Spinner_O.Value, app.y6Spinner_O.Value;
                app.x7Spinner_O.Value, app.y7Spinner_O.Value];
            build_bin_linear(movingPoints,fixedPoints, app.bands_P, outPath, false);
        end

        % Button pushed function: viewButton
        function viewButtonPushed(app, event)
            % plot the difference between linearly corrected P/M bands at
            % energy
            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please Wait', ...
            'Message','Starting analysis...', ...
            'Indeterminate','off', ...
            'Value',0);
             cla(app.UIAxes_band);

            energy = app.energyeVSpinner.Value;
            if ~strcmp(app.filepath_P, string(app.PmeasuredEditField.Value))
                app.filepath_P = string(app.PmeasuredEditField.Value);
                app.bands_P = load(string(app.PmeasuredEditField.Value));
            end
            if ~strcmp(app.filepath_M, string(app.MmeasuredEditField.Value))
                app.filepath_M = string(app.MmeasuredEditField.Value);
                app.bands_M = load(string(app.MmeasuredEditField.Value));
            end
            if ~strcmp(app.filepath_O, string(app.OmeasuredEditField.Value))
                app.filepath_O = string(app.OmeasuredEditField.Value);
                app.bands_O = load(string(app.OmeasuredEditField.Value));
            end

            movingPoints_P = [app.x1Spinner_P.Value, app.y1Spinner_P.Value;
                app.x2Spinner_P.Value, app.y2Spinner_P.Value;
                app.x3Spinner_P.Value, app.y3Spinner_P.Value;
                app.x4Spinner_P.Value, app.y4Spinner_P.Value;
                app.x5Spinner_P.Value, app.y5Spinner_P.Value;
                app.x6Spinner_P.Value, app.y6Spinner_P.Value;
                app.x7Spinner_P.Value, app.y7Spinner_P.Value];
            fixedPoints = [app.x1Spinner_O.Value, app.y1Spinner_O.Value;
                app.x2Spinner_O.Value, app.y2Spinner_O.Value;
                app.x3Spinner_O.Value, app.y3Spinner_O.Value;
                app.x4Spinner_O.Value, app.y4Spinner_O.Value;
                app.x5Spinner_O.Value, app.y5Spinner_O.Value;
                app.x6Spinner_O.Value, app.y6Spinner_O.Value;
                app.x7Spinner_O.Value, app.y7Spinner_O.Value];
            movingPoints_M = [app.x1Spinner_M.Value, app.y1Spinner_M.Value;
                app.x2Spinner_M.Value, app.y2Spinner_M.Value;
                app.x3Spinner_M.Value, app.y3Spinner_M.Value;
                app.x4Spinner_M.Value, app.y4Spinner_M.Value;
                app.x5Spinner_M.Value, app.y5Spinner_M.Value;
                app.x6Spinner_M.Value, app.y6Spinner_M.Value;
                app.x7Spinner_M.Value, app.y7Spinner_M.Value];

            [~, idx_P] = min(abs(app.bands_P.E-energy));
            [~, idx_M] = min(abs(app.bands_M.E-energy));

            I_P = squeeze(mean(app.bands_P.I_thetax_thetay(idx_P-2:idx_P+2, :, :),1));
            I_M = squeeze(mean(app.bands_M.I_thetax_thetay(idx_M-2:idx_M+2, :, :),1));
            thetax = app.bands_O.thetax;
            thetay = app.bands_O.thetay;
            %% linearly correct +/- fermi surfaces
            tform = fitgeotrans(movingPoints_P, fixedPoints, 'affine');
            [thetax_new_P, thetay_new_P] = transformPointsForward(tform, app.bands_P.thetax, app.bands_P.thetay);
            Ftheta = scatteredInterpolant(thetax_new_P(:), thetay_new_P(:), double(I_P(:)), 'linear', 'none');
            I_P_new = Ftheta(thetax, thetay);   % (H,D)
            idx = isnan(I_P_new);
            I_P_new(idx) = 0;
            
            tform = fitgeotrans(movingPoints_M, fixedPoints, 'affine');
            [thetax_new_M, thetay_new_M] = transformPointsForward(tform, app.bands_M.thetax, app.bands_M.thetay);
            Ftheta = scatteredInterpolant(thetax_new_M(:), thetay_new_M(:), double(I_M(:)), 'linear', 'none');
            I_M_new = Ftheta(thetax, thetay);   % (H,D)
            idx = isnan(I_M_new);
            I_M_new(idx) = 0;

            %% show the difference of +/- fermi surfaces
            switch app.kplotSwitch.Value
                case 'Off'
                    scatter(app.UIAxes_band, thetax(:), thetay(:), [], I_P_new(:)-I_M_new(:), 'filled');
                    axis(app.UIAxes_band, 'equal', 'tight');
                    xlabel(app.UIAxes_band, 'thetax [deg]');
                    ylabel(app.UIAxes_band, 'thetay [deg]');
                    title(app.UIAxes_band, 'Difference between corrected +field and -field fermi surface');
                    colormap(app.UIAxes_band, turbo); colorbar(app.UIAxes_band);   
                case 'On'
                    me   = 9.10938356e-31;     % electron mass [kg]
                    hbar = 1.054571817e-34;    % reduced Planck constant [J*s]
                    e   = 1.602176634e-19;    % 1 eV in joules

                    k_mag  = sqrt(2 * me * e * energy) / hbar / 1e10; % [1e10 m^-1]  it's a scalar
                    kx = k_mag .* tand(thetay).*cosd(thetax)./sqrt(1+tand(thetay).^2.*cosd(thetax).^2);
                    ky = k_mag .* tand(thetax).*cosd(thetay)./sqrt(1+tand(thetax).^2.*cosd(thetay).^2);
                    
                    scatter(app.UIAxes_band, kx(:), ky(:), [], I_P_new(:)-I_M_new(:), 'filled');
                    axis(app.UIAxes_band, 'equal', 'tight');
                    xlabel(app.UIAxes_band, 'kx [10^{10} m^{-1}]');
                    ylabel(app.UIAxes_band, 'ky [10^{10} m^{-1}]');
                    title(app.UIAxes_band, 'Difference between corrected +field and -field fermi surface');
                    colormap(app.UIAxes_band, turbo); colorbar(app.UIAxes_band);  
            end
            close(d);
        end

        % Button pushed function: autosearchButton
        function autosearchButtonPushed(app, event)
            nonFixed_mask = [app.transXCheckBox.Value, app.transXCheckBox.Value,...
                app.transYCheckBox.Value, app.transYCheckBox.Value, ...
                app.transZCheckBox.Value, app.transZCheckBox.Value,...
                app.thetaXCheckBox.Value, app.thetaXCheckBox.Value,...
                app.thetaYCheckBox.Value, app.thetaYCheckBox.Value,...
                app.thetaZCheckBox.Value, app.thetaZCheckBox.Value,...
                app.currentCheckBox.Value, app.currentCheckBox.Value, true];  % (1,15)
            labels = {"transX min(m)", "transX max(m)", "transY min(m)", "transY max(m)", "transZ min(m)", "transZ max(m)",...
                "thetaX min(deg)", "thetaX max(deg)", "thetaY min(deg)", "thetaY max(deg)", "thetaZ min(deg)", "thetaZ max(deg)",...
                "current min(A)", "current max(A)", "kr(10^10m^{-1})"};
            prompt = labels(nonFixed_mask);
            dlgtitle = 'Enter supplementary values for automatic search';
            dims = [1 35];
            defaultVals = {app.transXSpinner.Value, app.transXSpinner.Value,...
                app.transYSpinner.Value, app.transYSpinner.Value,...
                app.transZSpinner.Value, app.transZSpinner.Value,...
                app.thetaXSpinner.Value, app.thetaXSpinner.Value,...
                app.thetaYSpinner.Value, app.thetaYSpinner.Value,...
                app.thetaZSpinner.Value, app.thetaZSpinner.Value,...
                app.currentASpinner.Value, app.currentASpinner.Value, 1.0};
            defaultVals = string(defaultVals(nonFixed_mask));
            answer = inputdlg(prompt, dlgtitle, dims, defaultVals);  % answer: {'1', '2', ...}

            if isempty(answer)
                error('You need to input the range for auto search');
            end

            lb = zeros(7,1);
            ub = zeros(7,1);
            nonFixed_mask = [app.transXCheckBox.Value,...
                app.transYCheckBox.Value, ...
                app.transZCheckBox.Value,...
                app.thetaXCheckBox.Value,...
                app.thetaYCheckBox.Value,...
                app.thetaZCheckBox.Value,...
                app.currentCheckBox.Value];
            
            lb(nonFixed_mask) = str2double(answer(1:2:end-1));
            ub(nonFixed_mask) = str2double(answer(2:2:end));
            if any(isnan(lb)) || any(isnan(ub))
                error('please make sure put in numbers');
            end
            if any(ub-lb<0)
                error('please make sure the lower boundary is smaller than the upper boundary');
            end
            options.free_indices = find(nonFixed_mask); 
            options.similarity_method = 'delta';
            % convert the angles into radius, convert length to mm
            lb(1:3) = lb(1:3) * 1000;
            ub(1:3) = ub(1:3) * 1000;
            lb(4:6) = lb(4:6)/180*pi;
            ub(4:6) = ub(4:6)/180*pi;

            k_r = str2double(answer(end));
            initial_guess = [app.transXSpinner.Value*1000; app.transYSpinner.Value*1000; app.transZSpinner.Value*1000;...
                app.thetaXSpinner.Value/180*pi; app.thetaYSpinner.Value/180*pi; app.thetaZSpinner.Value/180*pi; app.currentASpinner.Value];

            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please Wait', ...
            'Message','Starting analysis...', ...
            'Indeterminate','off', ...
            'Value',0);

            if ~strcmp(app.filepath_P, string(app.PmeasuredTextArea.Value))   % TextArea is for correction panel, EditField is for linear correction
                app.filepath_P = string(app.PmeasuredTextArea.Value);
                app.bands_P = load(string(app.PmeasuredTextArea.Value));
            end
            if ~strcmp(app.filepath_M, string(app.MmeasuredTextArea.Value))
                app.filepath_M = string(app.MmeasuredTextArea.Value);
                app.bands_M = load(string(app.MmeasuredTextArea.Value));
            end
            if isempty(app.standard_field)
                try 
                    app.standard_field = load('./matdata/standard_field_withShield.mat');
                catch 
                    uialert(app.UIFigure, ...
                        'Please make sure there is standard_field.mat under the current directory',...
                        'Error');
                    return;
                end
            end

            f = figure('NumberTitle', 'off');
            % Create axes inside that figure
            ax = axes('Parent', f);  % This axis record the optimization curve
             
            best_p = optimized_parameters_dual_correction(app.bands_P, app.bands_M, k_r,...
                app.standard_field, initial_guess, lb, ub, ax, app.UIAxes_band,...
                app.EnergyeVEditField.Value, app.DeltaEmeVEditField.Value/1000, options);

            close(d);

            msg = sprintf('Results:\ntransX=%.4f(m), transY=%.4f(m), transZ=%.4f(m)\nthetaX=%.4f(deg), thetaY=%.4f(deg), thetaZ=%.4f(deg), current=%.4f(A)]\nApply these values?',...
              best_p.transX/1000, best_p.transY/1000, best_p.transZ/1000, best_p.thetaX*180/pi, best_p.thetaY*180/pi, best_p.thetaZ*180/pi, best_p.current);
            
            choice = questdlg(msg, ...
                              'Confirm Results', ...
                              'Yes','No','No');
            if strcmp(choice, 'Yes')
                app.transXSpinner.Value = best_p.transX/1000;
                app.transYSpinner.Value = best_p.transY/1000;
                app.transZSpinner.Value = best_p.transZ/1000;
                app.thetaXSpinner.Value = best_p.thetaX*180/pi;
                app.thetaYSpinner.Value = best_p.thetaY*180/pi;
                app.thetaZSpinner.Value = best_p.thetaZ*180/pi;
                app.currentASpinner.Value = best_p.current;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModesButtonGroup = uibuttongroup(app.LeftPanel);
            app.ModesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModesButtonGroupSelectionChanged, true);
            app.ModesButtonGroup.Title = 'Modes';
            app.modesDropDownLabel = uilabel(app.ModesButtonGroup);
            app.modesDropDownLabel.Text = 'modes';
            app.modesDropDown = uidropdown(app.ModesButtonGroup);
            app.modesDropDown.Items = {'standard field', 'import data', 'correction', 'correct all', 'linear distortion'};
            app.modesDropDown.ValueChangedFcn = createCallbackFcn(app, @modesDropDownValueChanged, true);
            app.modesDropDown.Value = 'standard field';
            app.importdataPanel = uipanel(app.LeftPanel);
            app.importdataPanel.Title = 'import data';
            app.importdataPanel.Visible = 'off';
            app.datapathEditFieldLabel = uilabel(app.importdataPanel);
            app.datapathEditFieldLabel.Text = 'data path';
            app.datapathEditField = uieditfield(app.importdataPanel, 'text');
            app.datatypeDropDownLabel = uilabel(app.importdataPanel);
            app.datatypeDropDownLabel.Text = 'data type';
            app.datatypeDropDown = uidropdown(app.importdataPanel);
            app.datatypeDropDown.Items = {'raw .h5', 'Igor .h5', 'to be implemented'};
            app.datatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @datatypeDropDownValueChanged, true);
            app.datatypeDropDown.Value = 'raw .h5';
            app.dataBrowseButton = uibutton(app.importdataPanel, 'push');
            app.dataBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @dataBrowseButtonPushed, true);
            app.dataBrowseButton.Text = 'Browse';
            app.outputpathEditFieldLabel = uilabel(app.importdataPanel);
            app.outputpathEditFieldLabel.Text = 'output path';
            app.outputpathEditField = uieditfield(app.importdataPanel, 'text');
            app.outputpathEditField.Tooltip = {'The path of the folder to store the output .mat file'};
            app.outputBrowseButton = uibutton(app.importdataPanel, 'push');
            app.outputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @outputBrowseButtonPushed, true);
            app.outputBrowseButton.Text = 'Browse';
            app.readdataButton = uibutton(app.importdataPanel, 'push');
            app.readdataButton.ButtonPushedFcn = createCallbackFcn(app, @readdataButtonPushed, true);
            app.readdataButton.Text = 'read data';
            app.TabGroup = uitabgroup(app.importdataPanel);
            app.TabGroup.Visible = 'off';
            app.igorh5Tab = uitab(app.TabGroup);
            app.igorh5Tab.Title = 'igor .h5';
            app.EfeVSpinnerLabel = uilabel(app.igorh5Tab);
            app.EfeVSpinnerLabel.Text = 'Ef (eV)';
            app.EfeVSpinner = uispinner(app.igorh5Tab);
            app.EfeVSpinner.Value = 110.56;
            app.energynumLabel = uilabel(app.igorh5Tab);
            app.energynumLabel.Text = 'energy: num';
            app.energynumEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.energynumEditField.Value = 799;
            app.thetaxnumLabel = uilabel(app.igorh5Tab);
            app.thetaxnumLabel.Text = 'thetax: num';
            app.thetaxnumEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaxnumEditField.Value = 361;
            app.thetaynumLabel = uilabel(app.igorh5Tab);
            app.thetaynumLabel.Text = 'thetay: num';
            app.thetaynumEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaynumEditField.Value = 281;
            app.energyoffsetEditFieldLabel = uilabel(app.igorh5Tab);
            app.energyoffsetEditFieldLabel.Text = 'offset';
            app.energyoffsetEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.energyoffsetEditField.Value = -1.34768;
            app.energydeltaEditFieldLabel = uilabel(app.igorh5Tab);
            app.energydeltaEditFieldLabel.Text = 'delta';
            app.energydeltaEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.energydeltaEditField.Value = 0.002;
            app.thetaxdeltaEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetaxdeltaEditFieldLabel.Text = 'delta';
            app.thetaxdeltaEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaxdeltaEditField.Value = 0.0998858;
            app.thetaxoffsetEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetaxoffsetEditFieldLabel.Text = 'offset';
            app.thetaxoffsetEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaxoffsetEditField.Value = -17.9795;
            app.thetayoffsetEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetayoffsetEditFieldLabel.Text = 'offset';
            app.thetayoffsetEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetayoffsetEditField.Value = -14;
            app.thetaydeltaEditFieldLabel = uilabel(app.igorh5Tab);
            app.thetaydeltaEditFieldLabel.Text = 'delta';
            app.thetaydeltaEditField = uieditfield(app.igorh5Tab, 'numeric');
            app.thetaydeltaEditField.Value = 0.1;
            app.standardfieldPanel = uipanel(app.LeftPanel);
            app.standardfieldPanel.Title = 'standard field';
            app.standardfieldPanel.Visible = 'off';
            app.ztargetmthemaximumzofsimulationLabel = uilabel(app.standardfieldPanel);
            app.ztargetmthemaximumzofsimulationLabel.Text = 'z target (m): the maximum z of simulation.';
            app.ztargetmthemaximumzofsimulationSpinner = uispinner(app.standardfieldPanel);
            app.ztargetmthemaximumzofsimulationSpinner.Value = 0.016;
            app.plottheconfigurationCheckBox = uicheckbox(app.standardfieldPanel);
            app.plottheconfigurationCheckBox.Text = 'plot the configuration';
            app.startbuildingstandardfieldButton = uibutton(app.standardfieldPanel, 'push');
            app.startbuildingstandardfieldButton.ButtonPushedFcn = createCallbackFcn(app, @startbuildingstandardfieldButtonPushed, true);
            app.startbuildingstandardfieldButton.Text = 'start building standard field';
            app.DescriptionTextAreaLabel = uilabel(app.standardfieldPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.standardfieldPanel);
            app.DescriptionTextArea.Editable = 'off';
            app.DescriptionTextArea.Value = {'standard field mode is used to simulate the field with the coil, core, and shield. The result is stored as ./matdata/standard_field.mat. If there is alredy such a .mat file, you don''t need to simulate the field again.'; '(notice this will usually take long time)'};
            app.lineardistortionPanel = uipanel(app.LeftPanel);
            app.lineardistortionPanel.Title = 'linear distortion';
            app.lineardistortionPanel.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.lineardistortionPanel);
            app.OTab = uitab(app.TabGroup2);
            app.OTab.Title = 'O';
            app.OmeasuredEditFieldLabel = uilabel(app.OTab);
            app.OmeasuredEditFieldLabel.Text = 'O measured';
            app.OmeasuredEditField = uieditfield(app.OTab, 'text');
            app.BrowseButton_O_linear = uibutton(app.OTab, 'push');
            app.BrowseButton_O_linear.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_O_linearPushed, true);
            app.BrowseButton_O_linear.Text = 'Browse';
            app.previewButton_O = uibutton(app.OTab, 'push');
            app.previewButton_O.ButtonPushedFcn = createCallbackFcn(app, @previewButton_OPushed, true);
            app.previewButton_O.Text = 'preview';
            app.x1SpinnerLabel = uilabel(app.OTab);
            app.x1SpinnerLabel.Text = 'x1';
            app.x1Spinner_O = uispinner(app.OTab);
            app.x1Spinner_O.ValueChangedFcn = createCallbackFcn(app, @x1Spinner_OValueChanged, true);
            app.y1SpinnerLabel = uilabel(app.OTab);
            app.y1SpinnerLabel.Text = 'y1';
            app.y1Spinner_O = uispinner(app.OTab);
            app.y1Spinner_O.ValueChangedFcn = createCallbackFcn(app, @y1Spinner_OValueChanged, true);
            app.x2SpinnerLabel = uilabel(app.OTab);
            app.x2SpinnerLabel.Text = 'x2';
            app.x2Spinner_O = uispinner(app.OTab);
            app.x2Spinner_O.ValueChangedFcn = createCallbackFcn(app, @x2Spinner_OValueChanged, true);
            app.y2SpinnerLabel = uilabel(app.OTab);
            app.y2SpinnerLabel.Text = 'y2';
            app.y2Spinner_O = uispinner(app.OTab);
            app.y2Spinner_O.ValueChangedFcn = createCallbackFcn(app, @y2Spinner_OValueChanged, true);
            app.y3SpinnerLabel = uilabel(app.OTab);
            app.y3SpinnerLabel.Text = 'y3';
            app.y3Spinner_O = uispinner(app.OTab);
            app.y3Spinner_O.ValueChangedFcn = createCallbackFcn(app, @y3Spinner_OValueChanged, true);
            app.x3SpinnerLabel = uilabel(app.OTab);
            app.x3SpinnerLabel.Text = 'x3';
            app.x3Spinner_O = uispinner(app.OTab);
            app.x3Spinner_O.ValueChangedFcn = createCallbackFcn(app, @x3Spinner_OValueChanged, true);
            app.x4SpinnerLabel = uilabel(app.OTab);
            app.x4SpinnerLabel.Text = 'x4';
            app.x4Spinner_O = uispinner(app.OTab);
            app.x4Spinner_O.ValueChangedFcn = createCallbackFcn(app, @x4Spinner_OValueChanged, true);
            app.y4SpinnerLabel = uilabel(app.OTab);
            app.y4SpinnerLabel.Text = 'y4';
            app.y4Spinner_O = uispinner(app.OTab);
            app.y4Spinner_O.ValueChangedFcn = createCallbackFcn(app, @y4Spinner_OValueChanged, true);
            app.x5SpinnerLabel = uilabel(app.OTab);
            app.x5SpinnerLabel.Text = 'x5';
            app.x5Spinner_O = uispinner(app.OTab);
            app.x5Spinner_O.ValueChangedFcn = createCallbackFcn(app, @x5Spinner_OValueChanged, true);
            app.y5SpinnerLabel = uilabel(app.OTab);
            app.y5SpinnerLabel.Text = 'y5';
            app.y5Spinner_O = uispinner(app.OTab);
            app.y5Spinner_O.ValueChangedFcn = createCallbackFcn(app, @y5Spinner_OValueChanged, true);
            app.y6SpinnerLabel = uilabel(app.OTab);
            app.y6SpinnerLabel.Text = 'y6';
            app.y6Spinner_O = uispinner(app.OTab);
            app.y6Spinner_O.ValueChangedFcn = createCallbackFcn(app, @y6Spinner_OValueChanged, true);
            app.x6SpinnerLabel = uilabel(app.OTab);
            app.x6SpinnerLabel.Text = 'x6';
            app.x6Spinner_O = uispinner(app.OTab);
            app.x6Spinner_O.ValueChangedFcn = createCallbackFcn(app, @x6Spinner_OValueChanged, true);
            app.x7SpinnerLabel = uilabel(app.OTab);
            app.x7SpinnerLabel.Text = 'x7';
            app.x7Spinner_O = uispinner(app.OTab);
            app.x7Spinner_O.ValueChangedFcn = createCallbackFcn(app, @x7Spinner_OValueChanged, true);
            app.y7SpinnerLabel = uilabel(app.OTab);
            app.y7SpinnerLabel.Text = 'y7';
            app.y7Spinner_O = uispinner(app.OTab);
            app.y7Spinner_O.ValueChangedFcn = createCallbackFcn(app, @y7Spinner_OValueChanged, true);
            app.energyeVSpinnerLabel = uilabel(app.OTab);
            app.energyeVSpinnerLabel.Text = 'energy(eV)';
            app.energyeVSpinner_O = uispinner(app.OTab);
            app.energyeVSpinner_O.Value = 110.56;
            app.PTab = uitab(app.TabGroup2);
            app.PTab.Title = 'P';
            app.PmeasuredEditFieldLabel = uilabel(app.PTab);
            app.PmeasuredEditFieldLabel.Text = 'P measured';
            app.PmeasuredEditField = uieditfield(app.PTab, 'text');
            app.BrowseButton_P_linear = uibutton(app.PTab, 'push');
            app.BrowseButton_P_linear.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_P_linearPushed, true);
            app.BrowseButton_P_linear.Text = 'Browse';
            app.x1Spinner_2Label = uilabel(app.PTab);
            app.x1Spinner_2Label.Text = 'x1';
            app.x1Spinner_P = uispinner(app.PTab);
            app.x1Spinner_P.ValueChangedFcn = createCallbackFcn(app, @x1Spinner_OValueChanged, true);
            app.y1SpinnerLabel_2 = uilabel(app.PTab);
            app.y1SpinnerLabel_2.Text = 'y1';
            app.y1Spinner_P = uispinner(app.PTab);
            app.y1Spinner_P.ValueChangedFcn = createCallbackFcn(app, @y1Spinner_OValueChanged, true);
            app.x2SpinnerLabel_2 = uilabel(app.PTab);
            app.x2SpinnerLabel_2.Text = 'x2';
            app.x2Spinner_P = uispinner(app.PTab);
            app.x2Spinner_P.ValueChangedFcn = createCallbackFcn(app, @x2Spinner_OValueChanged, true);
            app.y2SpinnerLabel_2 = uilabel(app.PTab);
            app.y2SpinnerLabel_2.Text = 'y2';
            app.y2Spinner_P = uispinner(app.PTab);
            app.y2Spinner_P.ValueChangedFcn = createCallbackFcn(app, @y2Spinner_OValueChanged, true);
            app.x3SpinnerLabel_2 = uilabel(app.PTab);
            app.x3SpinnerLabel_2.Text = 'x3';
            app.x3Spinner_P = uispinner(app.PTab);
            app.x3Spinner_P.ValueChangedFcn = createCallbackFcn(app, @x3Spinner_OValueChanged, true);
            app.y3SpinnerLabel_2 = uilabel(app.PTab);
            app.y3SpinnerLabel_2.Text = 'y3';
            app.y3Spinner_P = uispinner(app.PTab);
            app.y3Spinner_P.ValueChangedFcn = createCallbackFcn(app, @y3Spinner_OValueChanged, true);
            app.x4SpinnerLabel_2 = uilabel(app.PTab);
            app.x4SpinnerLabel_2.Text = 'x4';
            app.x4Spinner_P = uispinner(app.PTab);
            app.x4Spinner_P.ValueChangedFcn = createCallbackFcn(app, @x4Spinner_OValueChanged, true);
            app.y4SpinnerLabel_2 = uilabel(app.PTab);
            app.y4SpinnerLabel_2.Text = 'y4';
            app.y4Spinner_P = uispinner(app.PTab);
            app.y4Spinner_P.ValueChangedFcn = createCallbackFcn(app, @y4Spinner_OValueChanged, true);
            app.x5SpinnerLabel_2 = uilabel(app.PTab);
            app.x5SpinnerLabel_2.Text = 'x5';
            app.x5Spinner_P = uispinner(app.PTab);
            app.x5Spinner_P.ValueChangedFcn = createCallbackFcn(app, @x5Spinner_OValueChanged, true);
            app.y5SpinnerLabel_2 = uilabel(app.PTab);
            app.y5SpinnerLabel_2.Text = 'y5';
            app.y5Spinner_P = uispinner(app.PTab);
            app.y5Spinner_P.ValueChangedFcn = createCallbackFcn(app, @y5Spinner_OValueChanged, true);
            app.x6SpinnerLabel_2 = uilabel(app.PTab);
            app.x6SpinnerLabel_2.Text = 'x6';
            app.x6Spinner_P = uispinner(app.PTab);
            app.x6Spinner_P.ValueChangedFcn = createCallbackFcn(app, @x6Spinner_OValueChanged, true);
            app.y6SpinnerLabel_2 = uilabel(app.PTab);
            app.y6SpinnerLabel_2.Text = 'y6';
            app.y6Spinner_P = uispinner(app.PTab);
            app.y6Spinner_P.ValueChangedFcn = createCallbackFcn(app, @y6Spinner_OValueChanged, true);
            app.x7SpinnerLabel_2 = uilabel(app.PTab);
            app.x7SpinnerLabel_2.Text = 'x7';
            app.x7Spinner_P = uispinner(app.PTab);
            app.x7Spinner_P.ValueChangedFcn = createCallbackFcn(app, @x7Spinner_OValueChanged, true);
            app.y7SpinnerLabel_2 = uilabel(app.PTab);
            app.y7SpinnerLabel_2.Text = 'y7';
            app.y7Spinner_P = uispinner(app.PTab);
            app.y7Spinner_P.ValueChangedFcn = createCallbackFcn(app, @y7Spinner_OValueChanged, true);
            app.previewButton_P = uibutton(app.PTab, 'push');
            app.previewButton_P.ButtonPushedFcn = createCallbackFcn(app, @previewButton_PPushed, true);
            app.previewButton_P.Text = 'preview';
            app.energyeVSpinnerLabel_2 = uilabel(app.PTab);
            app.energyeVSpinnerLabel_2.Text = 'energy(eV)';
            app.energyeVSpinner_P = uispinner(app.PTab);
            app.energyeVSpinner_P.Value = 110.56;
            app.linearcorrectionButton_P = uibutton(app.PTab, 'push');
            app.linearcorrectionButton_P.ButtonPushedFcn = createCallbackFcn(app, @linearcorrectionButton_PPushed, true);
            app.linearcorrectionButton_P.Tooltip = {'The corrected data will be stored in export dir as .bin file'};
            app.linearcorrectionButton_P.Text = 'linear correction';
            app.exportdirEditField_2Label = uilabel(app.PTab);
            app.exportdirEditField_2Label.Text = 'export dir';
            app.exportdirEditField_P_linear = uieditfield(app.PTab, 'text');
            app.exportBrowseButton_P_linear = uibutton(app.PTab, 'push');
            app.exportBrowseButton_P_linear.ButtonPushedFcn = createCallbackFcn(app, @exportBrowseButton_P_linearPushed, true);
            app.exportBrowseButton_P_linear.Text = 'Browse';
            app.MTab = uitab(app.TabGroup2);
            app.MTab.Title = 'M';
            app.MmeasuredEditFieldLabel = uilabel(app.MTab);
            app.MmeasuredEditFieldLabel.Text = 'M measured';
            app.MmeasuredEditField = uieditfield(app.MTab, 'text');
            app.BrowseButton_M_linear = uibutton(app.MTab, 'push');
            app.BrowseButton_M_linear.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_M_linearPushed, true);
            app.BrowseButton_M_linear.Text = 'Browse';
            app.x1Spinner_2Label_2 = uilabel(app.MTab);
            app.x1Spinner_2Label_2.Text = 'x1';
            app.x1Spinner_M = uispinner(app.MTab);
            app.x1Spinner_M.ValueChangedFcn = createCallbackFcn(app, @x1Spinner_OValueChanged, true);
            app.y1SpinnerLabel_3 = uilabel(app.MTab);
            app.y1SpinnerLabel_3.Text = 'y1';
            app.y1Spinner_M = uispinner(app.MTab);
            app.y1Spinner_M.ValueChangedFcn = createCallbackFcn(app, @y1Spinner_OValueChanged, true);
            app.x2SpinnerLabel_3 = uilabel(app.MTab);
            app.x2SpinnerLabel_3.Text = 'x2';
            app.x2Spinner_M = uispinner(app.MTab);
            app.x2Spinner_M.ValueChangedFcn = createCallbackFcn(app, @x2Spinner_OValueChanged, true);
            app.y2SpinnerLabel_3 = uilabel(app.MTab);
            app.y2SpinnerLabel_3.Text = 'y2';
            app.y2Spinner_M = uispinner(app.MTab);
            app.y2Spinner_M.ValueChangedFcn = createCallbackFcn(app, @y2Spinner_OValueChanged, true);
            app.x3SpinnerLabel_3 = uilabel(app.MTab);
            app.x3SpinnerLabel_3.Text = 'x3';
            app.x3Spinner_M = uispinner(app.MTab);
            app.x3Spinner_M.ValueChangedFcn = createCallbackFcn(app, @x3Spinner_OValueChanged, true);
            app.y3SpinnerLabel_3 = uilabel(app.MTab);
            app.y3SpinnerLabel_3.Text = 'y3';
            app.y3Spinner_M = uispinner(app.MTab);
            app.y3Spinner_M.ValueChangedFcn = createCallbackFcn(app, @y3Spinner_OValueChanged, true);
            app.x4SpinnerLabel_3 = uilabel(app.MTab);
            app.x4SpinnerLabel_3.Text = 'x4';
            app.x4Spinner_M = uispinner(app.MTab);
            app.x4Spinner_M.ValueChangedFcn = createCallbackFcn(app, @x4Spinner_OValueChanged, true);
            app.y4SpinnerLabel_3 = uilabel(app.MTab);
            app.y4SpinnerLabel_3.Text = 'y4';
            app.y4Spinner_M = uispinner(app.MTab);
            app.y4Spinner_M.ValueChangedFcn = createCallbackFcn(app, @y4Spinner_OValueChanged, true);
            app.x5SpinnerLabel_3 = uilabel(app.MTab);
            app.x5SpinnerLabel_3.Text = 'x5';
            app.x5Spinner_M = uispinner(app.MTab);
            app.x5Spinner_M.ValueChangedFcn = createCallbackFcn(app, @x5Spinner_OValueChanged, true);
            app.y5SpinnerLabel_3 = uilabel(app.MTab);
            app.y5SpinnerLabel_3.Text = 'y5';
            app.y5Spinner_M = uispinner(app.MTab);
            app.y5Spinner_M.ValueChangedFcn = createCallbackFcn(app, @y5Spinner_OValueChanged, true);
            app.x6SpinnerLabel_3 = uilabel(app.MTab);
            app.x6SpinnerLabel_3.Text = 'x6';
            app.x6Spinner_M = uispinner(app.MTab);
            app.x6Spinner_M.ValueChangedFcn = createCallbackFcn(app, @x6Spinner_OValueChanged, true);
            app.y6SpinnerLabel_3 = uilabel(app.MTab);
            app.y6SpinnerLabel_3.Text = 'y6';
            app.y6Spinner_M = uispinner(app.MTab);
            app.y6Spinner_M.ValueChangedFcn = createCallbackFcn(app, @y6Spinner_OValueChanged, true);
            app.x7SpinnerLabel_3 = uilabel(app.MTab);
            app.x7SpinnerLabel_3.Text = 'x7';
            app.x7Spinner_M = uispinner(app.MTab);
            app.x7Spinner_M.ValueChangedFcn = createCallbackFcn(app, @x7Spinner_OValueChanged, true);
            app.y7SpinnerLabel_3 = uilabel(app.MTab);
            app.y7SpinnerLabel_3.Text = 'y7';
            app.y7Spinner_M = uispinner(app.MTab);
            app.y7Spinner_M.ValueChangedFcn = createCallbackFcn(app, @y7Spinner_OValueChanged, true);
            app.previewButton_M = uibutton(app.MTab, 'push');
            app.previewButton_M.ButtonPushedFcn = createCallbackFcn(app, @previewButton_MPushed, true);
            app.previewButton_M.Text = 'preview';
            app.energyeVSpinnerLabel_3 = uilabel(app.MTab);
            app.energyeVSpinnerLabel_3.Text = 'energy(eV)';
            app.energyeVSpinner_M = uispinner(app.MTab);
            app.energyeVSpinner_M.Value = 110.56;
            app.exportBrowseButton_M_linear = uibutton(app.MTab, 'push');
            app.exportBrowseButton_M_linear.ButtonPushedFcn = createCallbackFcn(app, @exportBrowseButton_M_linearPushed, true);
            app.exportBrowseButton_M_linear.Text = 'Browse';
            app.exportdirEditField_2Label_2 = uilabel(app.MTab);
            app.exportdirEditField_2Label_2.Text = 'export dir';
            app.exportdirEditField_M_linear = uieditfield(app.MTab, 'text');
            app.linearcorrectionButton_M = uibutton(app.MTab, 'push');
            app.linearcorrectionButton_M.ButtonPushedFcn = createCallbackFcn(app, @linearcorrectionButton_MPushed, true);
            app.linearcorrectionButton_M.Text = 'linear correction';
            app.SethighsymmetrypointsLabel = uilabel(app.lineardistortionPanel);
            app.SethighsymmetrypointsLabel.Text = 'Set high-symmetry points:';
            app.energyeVSpinnerLabel_4 = uilabel(app.lineardistortionPanel);
            app.energyeVSpinnerLabel_4.Text = 'energy(eV)';
            app.energyeVSpinner = uispinner(app.lineardistortionPanel);
            app.energyeVSpinner.Value = 110.56;
            app.viewButton = uibutton(app.lineardistortionPanel, 'push');
            app.viewButton.ButtonPushedFcn = createCallbackFcn(app, @viewButtonPushed, true);
            app.viewButton.Tooltip = {'See the difference between linear corrected P/M-field bands at energy(eV). Please make sure to select the points and filepaths beforehand. '};
            app.viewButton.Text = 'view';
            app.kplotSwitchLabel = uilabel(app.lineardistortionPanel);
            app.kplotSwitchLabel.Text = 'k-plot';
            app.kplotSwitch = uiswitch(app.lineardistortionPanel, 'slider');
            app.correctallPanel = uipanel(app.LeftPanel);
            app.correctallPanel.Title = 'correct all';
            app.correctallPanel.Visible = 'off';
            app.exportdirEditFieldLabel = uilabel(app.correctallPanel);
            app.exportdirEditFieldLabel.Text = 'export dir';
            app.exportdirEditField = uieditfield(app.correctallPanel, 'text');
            app.BrowseButton_export = uibutton(app.correctallPanel, 'push');
            app.BrowseButton_export.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_exportPushed, true);
            app.BrowseButton_export.Text = 'Browse';
            app.correctallButton = uibutton(app.correctallPanel, 'push');
            app.correctallButton.ButtonPushedFcn = createCallbackFcn(app, @correctallButtonPushed, true);
            app.correctallButton.Tooltip = {'Correct the two P/M bands below, using the parameters below. This may take some time. Corrected bands are stored as .bin'};
            app.correctallButton.Text = 'correct all';
            app.anglemapSwitchLabel = uilabel(app.correctallPanel);
            app.anglemapSwitchLabel.Text = 'angle-map';
            app.anglemapSwitch = uiswitch(app.correctallPanel, 'slider');
            app.correctionPanel = uipanel(app.LeftPanel);
            app.correctionPanel.Title = 'correction';
            app.transXSpinnerLabel = uilabel(app.correctionPanel);
            app.transXSpinnerLabel.Text = 'transX';
            app.transXSpinner = uispinner(app.correctionPanel);
            app.transXSpinner.ValueChangedFcn = createCallbackFcn(app, @transXSpinnerValueChanged, true);
            app.transYSpinnerLabel = uilabel(app.correctionPanel);
            app.transYSpinnerLabel.Text = 'transY';
            app.transYSpinner = uispinner(app.correctionPanel);
            app.transYSpinner.ValueChangedFcn = createCallbackFcn(app, @transYSpinnerValueChanged, true);
            app.transZSpinnerLabel = uilabel(app.correctionPanel);
            app.transZSpinnerLabel.Text = 'transZ';
            app.transZSpinner = uispinner(app.correctionPanel);
            app.transZSpinner.ValueChangedFcn = createCallbackFcn(app, @transZSpinnerValueChanged, true);
            app.thetaXSpinnerLabel = uilabel(app.correctionPanel);
            app.thetaXSpinnerLabel.Text = 'thetaX';
            app.thetaXSpinner = uispinner(app.correctionPanel);
            app.thetaXSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaXSpinnerValueChanged, true);
            app.thetaYSpinnerLabel = uilabel(app.correctionPanel);
            app.thetaYSpinnerLabel.Text = 'thetaY';
            app.thetaYSpinner = uispinner(app.correctionPanel);
            app.thetaYSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaYSpinnerValueChanged, true);
            app.thetaZSpinnerLabel = uilabel(app.correctionPanel);
            app.thetaZSpinnerLabel.Text = 'thetaZ';
            app.thetaZSpinner = uispinner(app.correctionPanel);
            app.thetaZSpinner.ValueChangedFcn = createCallbackFcn(app, @thetaZSpinnerValueChanged, true);
            app.currentASpinnerLabel = uilabel(app.correctionPanel);
            app.currentASpinnerLabel.Text = 'current(A)';
            app.currentASpinner = uispinner(app.correctionPanel);
            app.currentASpinner.ValueChangedFcn = createCallbackFcn(app, @currentASpinnerValueChanged, true);
            app.viewcorrectionButton = uibutton(app.correctionPanel, 'push');
            app.viewcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @viewcorrectionButtonPushed, true);
            app.viewcorrectionButton.Tooltip = {'Show the corrected pattern of the Energy chosen, to finetune the parameters'};
            app.viewcorrectionButton.Text = 'view correction';
            app.BrowseButton_M = uibutton(app.correctionPanel, 'push');
            app.BrowseButton_M.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_MPushed, true);
            app.BrowseButton_M.Text = 'Browse';
            app.MmeasuredTextAreaLabel = uilabel(app.correctionPanel);
            app.MmeasuredTextAreaLabel.Text = 'M measured';
            app.MmeasuredTextArea = uitextarea(app.correctionPanel);
            app.MmeasuredTextArea.Tooltip = {'.mat file containing 3D k-bands generated by importing minus field data'};
            app.BrowseButton_P = uibutton(app.correctionPanel, 'push');
            app.BrowseButton_P.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_PPushed, true);
            app.BrowseButton_P.Text = 'Browse';
            app.PmeasuredTextAreaLabel = uilabel(app.correctionPanel);
            app.PmeasuredTextAreaLabel.Text = 'P measured';
            app.PmeasuredTextArea = uitextarea(app.correctionPanel);
            app.PmeasuredTextArea.Tooltip = {'.mat file containing 3D k-bands generated by importing plus field data'};
            app.EnergyeVEditFieldLabel = uilabel(app.correctionPanel);
            app.EnergyeVEditFieldLabel.Text = 'Energy(eV)';
            app.EnergyeVEditField = uieditfield(app.correctionPanel, 'numeric');
            app.EnergyeVEditField.Limits = [0 Inf];
            app.EnergyeVEditField.ValueDisplayFormat = '%11.5g';
            app.EnergyeVEditField.Tooltip = {'The slice over which correction is made to examine the difference on the right figure. Usually fermi energy. '};
            app.EnergyeVEditField.Value = 110.56;
            app.DeltaEmeVEditFieldLabel = uilabel(app.correctionPanel);
            app.DeltaEmeVEditFieldLabel.Tooltip = {'take average in this range around "Energy"'};
            app.DeltaEmeVEditFieldLabel.Interpreter = 'latex';
            app.DeltaEmeVEditFieldLabel.Text = '\Delta E (meV)';
            app.DeltaEmeVEditField = uieditfield(app.correctionPanel, 'numeric');
            app.DeltaEmeVEditField.Limits = [0 Inf];
            app.DeltaEmeVEditField.Tooltip = {'The slice over which correction is made to examine the difference on the right figure. Usually fermi energy. '};
            app.DeltaEmeVEditField.Value = 10;
            app.autosearchButton = uibutton(app.correctionPanel, 'push');
            app.autosearchButton.ButtonPushedFcn = createCallbackFcn(app, @autosearchButtonPushed, true);
            app.autosearchButton.Tooltip = {'First enter the initial condition above.'};
            app.autosearchButton.Text = 'auto search';
            app.ParameterstotuneButtonGroup = uibuttongroup(app.correctionPanel);
            app.ParameterstotuneButtonGroup.Title = ' Parameters to tune';
            app.transXCheckBox = uicheckbox(app.ParameterstotuneButtonGroup);
            app.transXCheckBox.Text = 'transX';
            app.transXCheckBox.Value = true;
            app.transYCheckBox = uicheckbox(app.ParameterstotuneButtonGroup);
            app.transYCheckBox.Text = 'Y';
            app.transYCheckBox.Value = true;
            app.transZCheckBox = uicheckbox(app.ParameterstotuneButtonGroup);
            app.transZCheckBox.Text = 'Z';
            app.transZCheckBox.Value = true;
            app.thetaXCheckBox = uicheckbox(app.ParameterstotuneButtonGroup);
            app.thetaXCheckBox.Text = 'thetaX';
            app.thetaYCheckBox = uicheckbox(app.ParameterstotuneButtonGroup);
            app.thetaYCheckBox.Text = 'Y';
            app.thetaZCheckBox = uicheckbox(app.ParameterstotuneButtonGroup);
            app.thetaZCheckBox.Text = 'Z';
            app.thetaZCheckBox.Value = true;
            app.currentCheckBox = uicheckbox(app.ParameterstotuneButtonGroup);
            app.currentCheckBox.Text = 'current';
            app.currentCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_band = uiaxes(app.RightPanel);
            title(app.UIAxes_band, 'Title')
            xlabel(app.UIAxes_band, 'X')
            ylabel(app.UIAxes_band, 'Y')
            zlabel(app.UIAxes_band, 'Z')
            app.UIAxes_geometry = uiaxes(app.RightPanel);
            title(app.UIAxes_geometry, 'Title')
            xlabel(app.UIAxes_geometry, 'X')
            ylabel(app.UIAxes_geometry, 'Y')
            zlabel(app.UIAxes_geometry, 'Z')
            app.k_rradiusofthecentralareabeingplottedLabel = uilabel(app.RightPanel);
            app.k_rradiusofthecentralareabeingplottedLabel.Interpreter = 'latex';
            app.k_rradiusofthecentralareabeingplottedLabel.Text = 'k_r (\AA^{-1}):';
            app.k_rAA1Spinner = uispinner(app.RightPanel);
            app.k_rAA1Spinner.Tooltip = {'radius of the central area being plotted'};
            app.k_rAA1Spinner.Value = 1.5;
            app.manipulator_demo = uiimage(app.RightPanel);
            app.manipulator_demo.ImageClickedFcn = createCallbackFcn(app, @manipulator_demoClicked, true);
            app.manipulator_demo.ImageSource = fullfile(pathToMLAPP, 'figures', 'manipulator_demo.png');
            app.BcenterLabel = uilabel(app.RightPanel);
            app.BcenterLabel.Text = 'B(center)=';
            app.slit_demo = uiimage(app.RightPanel);
            app.slit_demo.ImageClickedFcn = createCallbackFcn(app, @slit_demoImageClicked, true);
            app.slit_demo.Visible = 'off';
            app.slit_demo.ImageSource = fullfile(pathToMLAPP, 'figures', 'slit_demo.png');
            app.meandeltaLabel = uilabel(app.RightPanel);
            app.meandeltaLabel.Text = 'mean delta = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end